classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        InputTab                       matlab.ui.container.Tab
        GraphsPanel                    matlab.ui.container.Panel
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        SpeechEnhancementAppLabel      matlab.ui.control.Label
        AudioInputPanel                matlab.ui.container.Panel
        SaveAudioButton                matlab.ui.control.Button
        ShowFrequencyDomainButton      matlab.ui.control.Button
        ShowTimeDomainButton           matlab.ui.control.Button
        VolumeSlider                   matlab.ui.control.Slider
        VolumeSliderLabel              matlab.ui.control.Label
        StopAudioButton                matlab.ui.control.Button
        PlayAudioButton                matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        RecordAudioButton              matlab.ui.control.Button
        ImportAudioButton              matlab.ui.control.Button
        NoiseTab                       matlab.ui.container.Tab
        GraphsPanel_2                  matlab.ui.container.Panel
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        NoisePanel                     matlab.ui.container.Panel
        NoNoiseAddedLabel              matlab.ui.control.Label
        NoiseLevelEditField            matlab.ui.control.NumericEditField
        NoiseLevelEditFieldLabel       matlab.ui.control.Label
        TypesofNoiseDropDown           matlab.ui.control.DropDown
        TypesofNoiseDropDownLabel      matlab.ui.control.Label
        SaveNoiseAudioButton           matlab.ui.control.Button
        ShowFrequencyDomainButton_2    matlab.ui.control.Button
        ShowTimeDomainButton_2         matlab.ui.control.Button
        VolumeSlider_2                 matlab.ui.control.Slider
        VolumeSlider_2Label            matlab.ui.control.Label
        StopNoiseAudioButton           matlab.ui.control.Button
        PlayNoiseAudioButton           matlab.ui.control.Button
        AddNoiseButton                 matlab.ui.control.Button
        SpeechEnhancementAppLabel_2    matlab.ui.control.Label
        FilterTab                      matlab.ui.container.Tab
        GraphsPanel_3                  matlab.ui.container.Panel
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        NoisePanel_2                   matlab.ui.container.Panel
        LowerCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        LowerCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        FilterOrderEditField           matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel      matlab.ui.control.Label
        UpperCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        UpperCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        NoNoiseAddedLabel_2            matlab.ui.control.Label
        TypesofFilterDropDown          matlab.ui.control.DropDown
        TypesofFilterDropDownLabel     matlab.ui.control.Label
        FilterNoiseButton              matlab.ui.control.Button
        FilteredAudioPanel             matlab.ui.container.Panel
        VolumeSlider_3                 matlab.ui.control.Slider
        VolumeSlider_3Label            matlab.ui.control.Label
        SaveFilteredAudioButton        matlab.ui.control.Button
        StopFilteredAudioButton        matlab.ui.control.Button
        PlayFilteredAudioButton        matlab.ui.control.Button
        ShowTimeDomainButton_3         matlab.ui.control.Button
        ShowFrequencyDomainButton_3    matlab.ui.control.Button
        SpeechEnhancementAppLabel_3    matlab.ui.control.Label
    end

    
    properties (Access = private)
        fullFilePath = '';  % Initialize the fullFilePath as an empty string
        audioData;          % Original audio data (without noise)
        noisyAudioData;     % Audio data with added noise
        filteredAudioData;  % Audio data after filtering
        fs;                 % To store the sampling frequency
        recorder;           % To store the audiorecorder object
        isRecording = false; % Flag to track recording state
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportAudioButton
        function ImportAudioButtonPushed(app, event)
           % Open file selection dialog for audio files
            [file, path] = uigetfile('*.mp3', 'Select an MP3 file');
            if isequal(file, 0)
                disp('User canceled the file selection');
            else
                % Update the fullFilePath property with the selected file path
                app.fullFilePath = fullfile(path, file);
                disp(['File selected: ', app.fullFilePath]);
                
                % Load the audio data and sampling frequency
                [app.audioData, app.fs] = audioread(app.fullFilePath);
            end
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if isempty(app.audioData)
                disp('No audio data to play. Please import an MP3 file or record audio first.');
            else
                % Get the current volume level from the slider
                volumeLevel = app.VolumeSlider.Value;

                % Adjust the audio data amplitude according to the volume level
                adjustedAudio = app.audioData * volumeLevel;

                % Play the adjusted audio
                sound(adjustedAudio, app.fs);
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            clear sound;  % Stop the audio
        end

        % Button pushed function: SaveAudioButton
        function SaveAudioButtonPushed(app, event)
            if isempty(app.audioData)
                disp('No audio data to save. Please import an MP3 file first.');
            else
                % Open file save dialog for MP3 file
                [file, path] = uiputfile('*.mp4', 'Save Audio as MP4 file');
                if isequal(file, 0)
                    disp('User canceled saving the file.');
                else
                    % Full file path to save
                    saveFilePath = fullfile(path, file);
                    
                    audiowrite(saveFilePath, app.audioData, app.fs, 'BitRate', 128); % Bitrate set to 128 kbps
                    disp(['Audio saved as MP4 to: ', saveFilePath]);
                end
            end
        end

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)
            if app.isRecording
                % Stop recording
                stop(app.recorder);
                app.Lamp.Color = 'red';
                disp('Recording stopped.');
                
                % Get the recorded audio data
                app.audioData = getaudiodata(app.recorder);
                app.fs = 44100;  % Set the sample rate for the recorded audio
                % Increase the volume by multiplying the audioData by a scaling factor
                volumeFactor = 2;  
                app.audioData = app.audioData * volumeFactor;

                % Ensure audio doesn't clip beyond the range [-1, 1]
                app.audioData = max(min(app.audioData, 1), -1);

                % Update the recording flag
                app.isRecording = false;
            else
                % Set up the audiorecorder object (44.1 kHz sample rate, 16 bits, 1 channel)
                app.recorder = audiorecorder(44100, 16, 1);
                
                % Start recording
                disp('Recording audio...');
                app.Lamp.Color = 'green';
                record(app.recorder);
                
                % Update the recording flag
                app.isRecording = true;
            end
        end

        % Button pushed function: ShowTimeDomainButton
        function ShowTimeDomainButtonPushed(app, event)
            if isempty(app.audioData)
                disp('No audio data to plot. Please import or record an audio file first.');
            else
                % Create a time vector based on the length of the audio data and the sampling rate
                t = (0:length(app.audioData)-1) / app.fs;

                % Plot the time-domain waveform
                plot(app.UIAxes, t, app.audioData);
                xlabel(app.UIAxes, 'Time (s)');
                ylabel(app.UIAxes, 'Amplitude');
                title(app.UIAxes, 'Time-Domain Waveform');
                grid(app.UIAxes, 'on');
            end
        end

        % Button pushed function: ShowFrequencyDomainButton
        function ShowFrequencyDomainButtonPushed(app, event)
            if isempty(app.audioData)
                disp('No audio data to analyze. Please import or record audio first.');
            else
                % Number of samples in the audio
                N = length(app.audioData);

                % Compute the FFT of the audio signal
                fftData = fft(app.audioData);

                % Compute the two-sided spectrum and then the single-sided spectrum
                P2 = abs(fftData / N);    % Two-sided spectrum
                P1 = P2(1:N/2+1);         % Single-sided spectrum (positive frequencies)
                P1(2:end-1) = 2 * P1(2:end-1);  % Scale the spectrum

                % Frequency vector (only positive frequencies)
                f = app.fs * (0:(N/2)) / N;

                % Plot the frequency spectrum on the UIAxes
                plot(app.UIAxes_2, f, P1);
                xlabel(app.UIAxes_2, 'Frequency (Hz)');
                ylabel(app.UIAxes_2, 'Magnitude');
                title(app.UIAxes_2, 'Frequency-Domain Spectrum');
                grid(app.UIAxes_2, 'on');
                xlim(app.UIAxes_2, [0 10000]);
            end
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            if isempty(app.audioData)
                disp('No audio data available. Please import or record audio first.');
            else
                % Get the selected noise type from the dropdown
                selectedNoise = app.TypesofNoiseDropDown.Value;
                noiseLevel = app.NoiseLevelEditField.Value;

                % Initialize the noisyAudioData as a copy of the original audio
                app.noisyAudioData = app.audioData;

                % Add noise based on the selected type
                switch selectedNoise
                    case 'White Noise'
                        noise = noiseLevel * randn(size(app.audioData));  % Generate white noise
                        app.noisyAudioData = app.audioData + noise;

                    case 'Babble Noise'
                        % Load a pre-recorded babble noise file
                        [babbleNoise, fsNoise] = audioread('babble_noise.wav');
                        % Resample babble noise to match the sample rate of the original audio
                        babbleNoise = resample(babbleNoise, app.fs, fsNoise);
                        % Trim or pad babble noise to match the length of the original audio data
                        babbleNoise = babbleNoise(1:length(app.audioData));
                        % Normalize the babble noise to the range [-1, 1]
                        babbleNoise = babbleNoise / max(abs(babbleNoise));
                        % Set a higher noise level to make the babble noise more noticeable
                        % Add babble noise to the original audio
                        app.noisyAudioData = app.audioData + noiseLevel * babbleNoise;
                  
                    case 'Pink Noise'
                        % Create a colored noise object for pink noise (exponent = 1)
                        cn = dsp.ColoredNoise('Color','pink','SamplesPerFrame',length(app.audioData),'NumChannels',1);
                        % Generate pink noise
                        pinkNoise = cn();
                        % Scale the pink noise by a fixed factor
                        pinkNoise = pinkNoise * noiseLevel;
                        % Add the pink noise to the original audio
                        app.noisyAudioData = app.audioData + pinkNoise;

                        
                    case 'Impulse Noise'
                        % Generate impulse noise
                        impulseAmplitude = 0.1;  % Amplitude of the impulse noise
                        numImpulses = 100;        % Number of impulses
                        impulseDuration = 10;    % Duration of each impulse in samples

                        % Determine the number of channels in the audio data
                        [~, numChannels] = size(app.audioData);

                        % Generate random positions for the impulses
                        impulsePositions = randi([1, length(app.audioData)], numImpulses, 1);
                
                        for i = 1:numImpulses
                            % Check if the impulse fits within the audio data
                            if impulsePositions(i) + impulseDuration - 1 <= length(app.audioData)
                                % Generate random impulse noise
                                impulse = impulseAmplitude * randn(impulseDuration, numChannels);  
                        
                                % Add the impulse to the corresponding position in the audio data
                                app.noisyAudioData(impulsePositions(i):impulsePositions(i) + impulseDuration - 1, :) = ...
                                app.noisyAudioData(impulsePositions(i):impulsePositions(i) + impulseDuration - 1, :) + impulse;
                            else
                                % If the impulse would exceed the length of the audio, adjust it
                                remainingSamples = length(app.audioData) - impulsePositions(i) + 1;
                                impulse = impulseAmplitude * randn(remainingSamples, numChannels);  % Adjust impulse length
                                app.noisyAudioData(impulsePositions(i):end, :) = ...
                                app.noisyAudioData(impulsePositions(i):end, :) + impulse;
                            end
                        end
                end

                % **Convert noisy audio data to mono by averaging channels**
                if size(app.noisyAudioData, 2) > 1
                    app.noisyAudioData = mean(app.noisyAudioData, 2);
                end
                % Ensure the signal stays within the valid range [-1, 1]
                app.noisyAudioData = max(min(app.noisyAudioData, 1), -1);
                
                 % Update the label to show which noise was added
                app.NoNoiseAddedLabel.Text = [selectedNoise ' added to the audio!'];

            end
        end

        % Button pushed function: PlayNoiseAudioButton
        function PlayNoiseAudioButtonPushed(app, event)
            if isempty(app.noisyAudioData)
                disp('No audio data to play. Please add noise first.');
            else
                % Get the current volume level from the slider
                volumeLevel = app.VolumeSlider_2.Value;

                % Adjust the audio data amplitude according to the volume level
                adjustedAudio = app.noisyAudioData * volumeLevel;
            
                % Play the adjusted audio
                sound(adjustedAudio, app.fs);
            end
        end

        % Button pushed function: StopNoiseAudioButton
        function StopNoiseAudioButtonPushed(app, event)
            clear sound;  % Stop the audio
        end

        % Button pushed function: SaveNoiseAudioButton
        function SaveNoiseAudioButtonPushed(app, event)
            if isempty(app.noisyAudioData)
                disp('No audio data to save. Please Add noise first.');
            else
                % Open file save dialog for MP3 file
                [file, path] = uiputfile('*.mp4', 'Save Audio as MP4 file');
                if isequal(file, 0)
                    disp('User canceled saving the file.');
                else
                    % Full file path to save
                    saveFilePath = fullfile(path, file);
                    
                    audiowrite(saveFilePath, app.noisyAudioData, app.fs, 'BitRate', 128); % Bitrate set to 128 kbps
                    disp(['Audio saved as MP4 to: ', saveFilePath]);
                end
            end
        end

        % Button pushed function: ShowTimeDomainButton_2
        function ShowTimeDomainButton_2Pushed(app, event)
            if isempty(app.noisyAudioData)
                disp('No audio data to plot. Please add noise first.');
            else
               % Create a time vector based on the length of the audio data and the sampling rate
               t = (0:length(app.audioData)-1) / app.fs;
             
                % Plot the noisy audio data (original + noise)
                plot(app.UIAxes_3, t, app.noisyAudioData, 'r', 'LineWidth', 1.5);  % Plot noisy in red
                hold(app.UIAxes_3, 'on');  % Keep the plot to add noisy data

                % Plot the original audio data
                plot(app.UIAxes_3, t, app.audioData, 'b', 'LineWidth', 1.5);  % Plot original in blue
                
              
                % Add labels and title
                xlabel(app.UIAxes_3, 'Time (s)');
                ylabel(app.UIAxes_3, 'Amplitude');
                title(app.UIAxes_3, 'Time-Domain Waveform: Original vs. Noisy');

                % Add a legend to distinguish the two plots
                legend(app.UIAxes_3, {'Noisy Audio', 'Original Audio'}, 'Location', 'best');
                
                % Enable grid
                grid(app.UIAxes_3, 'on');

                % Release the hold on the axes
                hold(app.UIAxes_3, 'off');
            end
        end

        % Button pushed function: ShowFrequencyDomainButton_2
        function ShowFrequencyDomainButton_2Pushed(app, event)
            if isempty(app.noisyAudioData)
                disp('No audio data to analyze. Please add noise first.');
            else
                % Number of samples in the audio
                N = length(app.noisyAudioData);

                % Compute the FFT of the original audio signal
                fftOriginal = fft(app.audioData);
                P2_original = abs(fftOriginal / N);    % Two-sided spectrum
                P1_original = P2_original(1:N/2+1);    % Single-sided spectrum (positive frequencies)
                P1_original(2:end-1) = 2 * P1_original(2:end-1);  % Scale the spectrum

                % Compute the FFT of the noisy audio signal
                fftNoisy = fft(app.noisyAudioData);
                P2_noisy = abs(fftNoisy / N);    % Two-sided spectrum
                P1_noisy = P2_noisy(1:N/2+1);    % Single-sided spectrum (positive frequencies)
                P1_noisy(2:end-1) = 2 * P1_noisy(2:end-1);  % Scale the spectrum

                % Frequency vector (only positive frequencies)
                f = app.fs * (0:(N/2)) / N;

                % Plot the noisy audio frequency spectrum
                plot(app.UIAxes_4, f, P1_noisy, 'r', 'LineWidth', 1.5);  % Plot noisy in red
                hold(app.UIAxes_4, 'on');  % Hold the plot to add noisy spectrum

                % Plot the original audio frequency spectrum
                plot(app.UIAxes_4, f, P1_original, 'b', 'LineWidth', 1.5);  % Plot original in blue

                % Add labels, title, and legend
                xlabel(app.UIAxes_4, 'Frequency (Hz)');
                ylabel(app.UIAxes_4, 'Magnitude');
                title(app.UIAxes_4, 'Frequency-Domain Spectrum: Original vs. Noisy');
                legend(app.UIAxes_4, {'Noisy Audio', 'Original Audio'}, 'Location', 'best');

                % Enable grid
                grid(app.UIAxes_4, 'on');
                xlim(app.UIAxes_4, [0 10000]);
                % Release the hold on the axes
                hold(app.UIAxes_4, 'off');
            end
        end

        % Button pushed function: FilterNoiseButton
        function FilterNoiseButtonPushed(app, event)
            if isempty(app.noisyAudioData)
                disp('No noisy audio data available. Please add noise to the audio first.');
            else
                % Get the selected filter type from the dropdown menu
                selectedFilter = app.TypesofFilterDropDown.Value; 
                lowCutoff = app.LowerCutoffFrequencyEditField.Value;
                highCutoff = app.UpperCutoffFrequencyEditField.Value;
                filterOrder = app.FilterOrderEditField.Value;

                switch selectedFilter
                    case 'Butterworth Filter'
                        % Design the bandpass-pass Butterworth filter
                        [b, a] = butter(filterOrder, [lowCutoff, highCutoff] / (app.fs / 2), 'bandpass');
                        % Apply the filter to the noisy audio
                        app.filteredAudioData = filtfilt(b, a, app.noisyAudioData);
                    
                    case 'Chebyshev Filter'
                        wn = [lowCutoff, highCutoff] / (app.fs / 2);  % Normalized frequencies
                        Rs = 60;              % Stopband attenuation in dB

                        % Design a Chebyshev Type I low-pass filter
                        [b, a] = cheby2(filterOrder, Rs, wn, 'bandpass');

                        % Apply the filter to the noisy audio data
                        filteredAudio = filtfilt(b, a, app.noisyAudioData);
                        
                        % Normalize the output
                        filteredAudio = filteredAudio / max(abs(filteredAudio));
                        app.filteredAudioData = filteredAudio;

                    case 'FIR Filter'
                       wn = [lowCutoff, highCutoff] / (app.fs / 2);
                       b = fir1(filterOrder, wn, 'bandpass', hamming(filterOrder + 1));
                       app.filteredAudioData = filtfilt(b, 1, app.noisyAudioData);
                    
                    case 'Median Filter'
                        % Apply a median filter
                        app.filteredAudioData = medfilt1(app.noisyAudioData, filterOrder);  % 11-point median filter

                    case 'Spectral Subtraction'
                        noisyAudio = app.noisyAudioData;
                        [noiseReference, fs_noise] = audioread('babble_noise.wav');
                        noiseReference = resample(noiseReference, app.fs, fs_noise);
                        windowLength = 1024;                        % Length of each segment
                        overlapLength = round(0.75 * windowLength); % Overlap between segments
                        fftLength = 1024;                           % Number of FFT points
                        window = hamming(windowLength, 'periodic'); % Hamming window

                        % Perform STFT on the noisy signal
                        [S_noisy, ~, ~] = stft(noisyAudio, app.fs, 'Window', window, 'OverlapLength', overlapLength, ...
                            'FFTLength', fftLength);
                        % Perform STFT on the noise reference signal
                        [S_noise, ~, ~] = stft(noiseReference, app.fs, 'Window', window, 'OverlapLength', overlapLength, ...
                            'FFTLength', fftLength);
                        
                        noisePowerSpectrum = mean(abs(S_noise).^2, 2);
                        
                        S_clean = zeros(size(S_noisy));

                        % Spectral subtraction parameters
                        alpha = 0.5;     % Over-subtraction factor (adjust as needed)
                        beta = 0.05;   % Spectral floor factor (adjust as needed)
                        % Define smoothing parameters
                        smoothingSpan = 5;  % Must be an odd number

                        % Spectral subtraction with smoothing
                        for i = 1:size(S_noisy, 2)
                            Yk = abs(S_noisy(:, i));
                            % Estimate the clean speech magnitude spectrum
                            Sk = Yk - alpha * sqrt(noisePowerSpectrum);
                            % Apply spectral floor
                            Sk = max(Sk, beta * Yk);
                            % Apply frequency-domain smoothing
                            Sk_smoothed = smoothdata(Sk, 'movmean', smoothingSpan);
                            % Reconstruct the complex spectrum
                            S_clean(:, i) = Sk_smoothed .* exp(1j * angle(S_noisy(:, i)));
                        end

                        % Perform inverse STFT to get the time-domain signal
                        cleanAudio = istft(S_clean, app.fs, ...
                            'Window', window, 'OverlapLength', overlapLength, ...
                            'FFTLength', fftLength);

                        % Ensure the output length matches the input
                        lenNoisy = length(noisyAudio);
                        lenClean = length(cleanAudio);
                        
                        if lenClean < lenNoisy
                            cleanAudio(lenClean+1:lenNoisy) = 0;
                        elseif lenClean > lenNoisy
                            cleanAudio = cleanAudio(1:lenNoisy);
                        end

                        cleanAudio = cleanAudio(1:length(noisyAudio));
                        cleanAudio = real(cleanAudio);

                        % Normalize the output
                        app.filteredAudioData = cleanAudio / max(abs(cleanAudio));
                end

                % Ensure the filtered audio stays within the valid range [-1, 1]
                app.filteredAudioData = max(min(app.filteredAudioData, 1), -1);

                % Update the label to show which noise was added
                app.NoNoiseAddedLabel_2.Text = [selectedFilter ' used!'];
            end
        end

        % Button pushed function: ShowTimeDomainButton_3
        function ShowTimeDomainButton_3Pushed(app, event)
            if isempty(app.filteredAudioData)
                disp('No audio data to plot. Please filter the audio first.');
            else
               % Create a time vector based on the length of the audio data and the sampling rate
               t = (0:length(app.audioData)-1) / app.fs;
             
                % Plot the noisy audio data (original + noise)
                plot(app.UIAxes_5, t, app.noisyAudioData, 'r', 'LineWidth', 1.5);  % Plot noisy in red
                hold(app.UIAxes_5, 'on');  % Keep the plot to add noisy data

                % Plot the original audio data
                plot(app.UIAxes_5, t, app.filteredAudioData, 'b', 'LineWidth', 1.5);  % Plot original in blue
               
                % Add labels and title
                xlabel(app.UIAxes_5, 'Time (s)');
                ylabel(app.UIAxes_5, 'Amplitude');
                title(app.UIAxes_5, 'Time-Domain Waveform: Filtered vs. Noisy');

                % Add a legend to distinguish the two plots
                legend(app.UIAxes_5, {'Noisy Audio', 'Filtered Audio'}, 'Location', 'best');
                
                % Enable grid
                grid(app.UIAxes_5, 'on');

                % Release the hold on the axes
                hold(app.UIAxes_5, 'off');
            end
           
        end

        % Button pushed function: ShowFrequencyDomainButton_3
        function ShowFrequencyDomainButton_3Pushed(app, event)
            if isempty(app.filteredAudioData)
                disp('No audio data to analyze. Please filter the audio first.');
            else
                % Number of samples in the audio
                N = length(app.filteredAudioData);

                % Compute the FFT of the filtered audio signal
                fftOriginal = fft(app.filteredAudioData);
                P2_original = abs(fftOriginal / N);    % Two-sided spectrum
                P1_original = P2_original(1:N/2+1);    % Single-sided spectrum (positive frequencies)
                P1_original(2:end-1) = 2 * P1_original(2:end-1);  % Scale the spectrum

                % Compute the FFT of the noisy audio signal
                fftNoisy = fft(app.noisyAudioData);
                P2_noisy = abs(fftNoisy / N);    % Two-sided spectrum
                P1_noisy = P2_noisy(1:N/2+1);    % Single-sided spectrum (positive frequencies)
                P1_noisy(2:end-1) = 2 * P1_noisy(2:end-1);  % Scale the spectrum

                % Frequency vector (only positive frequencies)
                f = app.fs * (0:(N/2)) / N;

                % Plot the noisy audio frequency spectrum
                plot(app.UIAxes_6, f, P1_noisy, 'r', 'LineWidth', 1.5);  % Plot noisy in red
                hold(app.UIAxes_6, 'on');  % Hold the plot to add noisy spectrum

                % Plot the original audio frequency spectrum
                plot(app.UIAxes_6, f, P1_original, 'b', 'LineWidth', 1.5);  % Plot original in blue

                % Add labels, title, and legend
                xlabel(app.UIAxes_6, 'Frequency (Hz)');
                ylabel(app.UIAxes_6, 'Magnitude');
                title(app.UIAxes_6, 'Frequency-Domain Spectrum: Filtered vs. Noisy');
                legend(app.UIAxes_6, {'Noisy Audio', 'Filtered Audio'}, 'Location', 'best');

                % Enable grid
                grid(app.UIAxes_6, 'on');
                xlim(app.UIAxes_6, [0 10000]);

                % Release the hold on the axes
                hold(app.UIAxes_6, 'off');
            end
        end

        % Button pushed function: SaveFilteredAudioButton
        function SaveFilteredAudioButtonPushed(app, event)
            if isempty(app.filteredAudioData)
                disp('No audio data to save. Please filter the audio first.');
            else
                % Open file save dialog for MP3 file
                [file, path] = uiputfile('*.mp4', 'Save Audio as MP4 file');
                if isequal(file, 0)
                    disp('User canceled saving the file.');
                else
                    % Full file path to save
                    saveFilePath = fullfile(path, file);
                    
                    audiowrite(saveFilePath, app.filteredAudioData, app.fs, 'BitRate', 128); % Bitrate set to 128 kbps
                    disp(['Audio saved as MP4 to: ', saveFilePath]);
                end
            end
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            if isempty(app.filteredAudioData)
                disp('No audio data to play. Please filter the audio first.');
            else
                % Get the current volume level from the slider
                volumeLevel = app.VolumeSlider_3.Value;

                % Adjust the audio data amplitude according to the volume level
                adjustedAudio = app.filteredAudioData * volumeLevel;
            
                % Play the adjusted audio
                sound(adjustedAudio, app.fs);
            end
        end

        % Button pushed function: StopFilteredAudioButton
        function StopFilteredAudioButtonPushed(app, event)
            clear sound;  % Stop the audio
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.AudioInputPanel = uipanel(app.InputTab);
            app.AudioInputPanel.Title = 'Audio Input';
            app.ImportAudioButton = uibutton(app.AudioInputPanel, 'push');
            app.ImportAudioButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioButtonPushed, true);
            app.ImportAudioButton.Text = 'Import Audio';
            app.RecordAudioButton = uibutton(app.AudioInputPanel, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.Lamp = uilamp(app.AudioInputPanel);
            app.PlayAudioButton = uibutton(app.AudioInputPanel, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.StopAudioButton = uibutton(app.AudioInputPanel, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.VolumeSliderLabel = uilabel(app.AudioInputPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.AudioInputPanel);
            app.VolumeSlider.Limits = [0 10];
            app.VolumeSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.VolumeSlider.Value = 5;
            app.ShowTimeDomainButton = uibutton(app.AudioInputPanel, 'push');
            app.ShowTimeDomainButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTimeDomainButtonPushed, true);
            app.ShowTimeDomainButton.Text = 'Show Time Domain';
            app.ShowFrequencyDomainButton = uibutton(app.AudioInputPanel, 'push');
            app.ShowFrequencyDomainButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFrequencyDomainButtonPushed, true);
            app.ShowFrequencyDomainButton.Text = 'Show Frequency Domain';
            app.SaveAudioButton = uibutton(app.AudioInputPanel, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioButtonPushed, true);
            app.SaveAudioButton.Text = 'Save Audio';
            app.SpeechEnhancementAppLabel = uilabel(app.InputTab);
            app.SpeechEnhancementAppLabel.Text = 'Speech Enhancement App';
            app.GraphsPanel = uipanel(app.InputTab);
            app.GraphsPanel.Title = 'Graphs';
            app.UIAxes = uiaxes(app.GraphsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GraphsPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.SpeechEnhancementAppLabel_2 = uilabel(app.NoiseTab);
            app.SpeechEnhancementAppLabel_2.Text = 'Speech Enhancement App';
            app.NoisePanel = uipanel(app.NoiseTab);
            app.NoisePanel.Title = 'Noise';
            app.AddNoiseButton = uibutton(app.NoisePanel, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            app.PlayNoiseAudioButton = uibutton(app.NoisePanel, 'push');
            app.PlayNoiseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoiseAudioButtonPushed, true);
            app.PlayNoiseAudioButton.Text = 'Play Noise Audio';
            app.StopNoiseAudioButton = uibutton(app.NoisePanel, 'push');
            app.StopNoiseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopNoiseAudioButtonPushed, true);
            app.StopNoiseAudioButton.Text = 'Stop Noise Audio';
            app.VolumeSlider_2Label = uilabel(app.NoisePanel);
            app.VolumeSlider_2Label.Text = 'Volume';
            app.VolumeSlider_2 = uislider(app.NoisePanel);
            app.VolumeSlider_2.Limits = [0 10];
            app.VolumeSlider_2.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.VolumeSlider_2.Value = 5;
            app.ShowTimeDomainButton_2 = uibutton(app.NoisePanel, 'push');
            app.ShowTimeDomainButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowTimeDomainButton_2Pushed, true);
            app.ShowTimeDomainButton_2.Text = 'Show Time Domain';
            app.ShowFrequencyDomainButton_2 = uibutton(app.NoisePanel, 'push');
            app.ShowFrequencyDomainButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowFrequencyDomainButton_2Pushed, true);
            app.ShowFrequencyDomainButton_2.Text = 'Show Frequency Domain';
            app.SaveNoiseAudioButton = uibutton(app.NoisePanel, 'push');
            app.SaveNoiseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNoiseAudioButtonPushed, true);
            app.SaveNoiseAudioButton.Text = 'Save Noise Audio';
            app.TypesofNoiseDropDownLabel = uilabel(app.NoisePanel);
            app.TypesofNoiseDropDownLabel.Text = 'Types of Noise';
            app.TypesofNoiseDropDown = uidropdown(app.NoisePanel);
            app.TypesofNoiseDropDown.Items = {'White Noise', 'Babble Noise', 'Impulse Noise', 'Pink Noise'};
            app.TypesofNoiseDropDown.Value = 'White Noise';
            app.NoiseLevelEditFieldLabel = uilabel(app.NoisePanel);
            app.NoiseLevelEditFieldLabel.Text = 'Noise Level';
            app.NoiseLevelEditField = uieditfield(app.NoisePanel, 'numeric');
            app.NoiseLevelEditField.Value = 0.1;
            app.NoNoiseAddedLabel = uilabel(app.NoisePanel);
            app.NoNoiseAddedLabel.Text = 'No Noise Added!';
            app.GraphsPanel_2 = uipanel(app.NoiseTab);
            app.GraphsPanel_2.Title = 'Graphs';
            app.UIAxes_3 = uiaxes(app.GraphsPanel_2);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.GraphsPanel_2);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.SpeechEnhancementAppLabel_3 = uilabel(app.FilterTab);
            app.SpeechEnhancementAppLabel_3.Text = 'Speech Enhancement App';
            app.FilteredAudioPanel = uipanel(app.FilterTab);
            app.FilteredAudioPanel.Title = 'Filtered Audio';
            app.ShowFrequencyDomainButton_3 = uibutton(app.FilteredAudioPanel, 'push');
            app.ShowFrequencyDomainButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowFrequencyDomainButton_3Pushed, true);
            app.ShowFrequencyDomainButton_3.Text = 'Show Frequency Domain';
            app.ShowTimeDomainButton_3 = uibutton(app.FilteredAudioPanel, 'push');
            app.ShowTimeDomainButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowTimeDomainButton_3Pushed, true);
            app.ShowTimeDomainButton_3.Text = 'Show Time Domain';
            app.PlayFilteredAudioButton = uibutton(app.FilteredAudioPanel, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.StopFilteredAudioButton = uibutton(app.FilteredAudioPanel, 'push');
            app.StopFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopFilteredAudioButtonPushed, true);
            app.StopFilteredAudioButton.Text = 'Stop Filtered Audio';
            app.SaveFilteredAudioButton = uibutton(app.FilteredAudioPanel, 'push');
            app.SaveFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFilteredAudioButtonPushed, true);
            app.SaveFilteredAudioButton.Text = 'Save Filtered Audio';
            app.VolumeSlider_3Label = uilabel(app.FilteredAudioPanel);
            app.VolumeSlider_3Label.Text = 'Volume';
            app.VolumeSlider_3 = uislider(app.FilteredAudioPanel);
            app.VolumeSlider_3.Limits = [0 10];
            app.VolumeSlider_3.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.VolumeSlider_3.Value = 5;
            app.NoisePanel_2 = uipanel(app.FilterTab);
            app.NoisePanel_2.Title = 'Noise';
            app.FilterNoiseButton = uibutton(app.NoisePanel_2, 'push');
            app.FilterNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @FilterNoiseButtonPushed, true);
            app.FilterNoiseButton.Text = 'Filter Noise';
            app.TypesofFilterDropDownLabel = uilabel(app.NoisePanel_2);
            app.TypesofFilterDropDownLabel.Text = 'Types of Filter';
            app.TypesofFilterDropDown = uidropdown(app.NoisePanel_2);
            app.TypesofFilterDropDown.Items = {'Butterworth Filter', 'Chebyshev Filter', 'Median Filter', 'FIR Filter', 'Spectral Subtraction'};
            app.TypesofFilterDropDown.Value = 'Butterworth Filter';
            app.NoNoiseAddedLabel_2 = uilabel(app.NoisePanel_2);
            app.NoNoiseAddedLabel_2.Text = 'No Noise Added!';
            app.UpperCutoffFrequencyEditFieldLabel = uilabel(app.NoisePanel_2);
            app.UpperCutoffFrequencyEditFieldLabel.Text = 'Upper Cutoff Frequency';
            app.UpperCutoffFrequencyEditField = uieditfield(app.NoisePanel_2, 'numeric');
            app.UpperCutoffFrequencyEditField.Value = 3000;
            app.FilterOrderEditFieldLabel = uilabel(app.NoisePanel_2);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.NoisePanel_2, 'numeric');
            app.FilterOrderEditField.Value = 4;
            app.LowerCutoffFrequencyEditFieldLabel = uilabel(app.NoisePanel_2);
            app.LowerCutoffFrequencyEditFieldLabel.Text = 'Lower Cutoff Frequency';
            app.LowerCutoffFrequencyEditField = uieditfield(app.NoisePanel_2, 'numeric');
            app.LowerCutoffFrequencyEditField.Value = 20;
            app.GraphsPanel_3 = uipanel(app.FilterTab);
            app.GraphsPanel_3.Title = 'Graphs';
            app.UIAxes_5 = uiaxes(app.GraphsPanel_3);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.GraphsPanel_3);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end